classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        dataFileName      matlab.ui.control.EditField
        readData          matlab.ui.control.Button
        visual            matlab.ui.container.Panel
        ButtonGroup_2     matlab.ui.container.ButtonGroup
        Button_stft       matlab.ui.control.ToggleButton
        Button_DEF        matlab.ui.control.ToggleButton
        Button_Q          matlab.ui.control.ToggleButton
        Button_P          matlab.ui.control.ToggleButton
        NoneButton        matlab.ui.control.ToggleButton
        ButtonGroup       matlab.ui.container.ButtonGroup
        Button_I_2        matlab.ui.control.ToggleButton
        Button_ij         matlab.ui.control.ToggleButton
        Button_I          matlab.ui.control.ToggleButton
        Button_uj         matlab.ui.control.ToggleButton
        Button_U          matlab.ui.control.ToggleButton
        Localization      matlab.ui.container.Panel
        ResultBus         matlab.ui.control.EditField
        Label_4           matlab.ui.control.Label
        LocBus            matlab.ui.control.Button
        ResultArea        matlab.ui.control.EditField
        Label_3           matlab.ui.control.Label
        LocArea           matlab.ui.control.Button
        displayStr        matlab.ui.control.EditField
        Label_2           matlab.ui.control.Label
        TabGroup          matlab.ui.container.TabGroup
        TabImageLoc       matlab.ui.container.Tab
        Image_Area        matlab.ui.control.Image
        Image_Bus         matlab.ui.control.Image
        TabVisualInput    matlab.ui.container.Tab
        EditIndex         matlab.ui.control.EditField
        Label_5           matlab.ui.control.Label
        UITable           matlab.ui.control.Table
        UIAxes_time       matlab.ui.control.UIAxes
        TabVisualFeature  matlab.ui.container.Tab
        EditIndexFeature  matlab.ui.control.EditField
        Label_6           matlab.ui.control.Label
        UITableFeature    matlab.ui.control.Table
        UIAxes_Feature    matlab.ui.control.UIAxes
        Label             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: readData
        function readDataButtonPushed(app, event)
            app.dataFileName.Value = '240Bus-30Hz-TwoArea-Fault-UnobservableSource data';
            app.displayStr.Value = '读入数据完成。';
%             filepath = './data8-240/case1';
%             [P, targetI] = LoadCsvBus([filepath 'PowerP.csv']);
            
        end

        % Button pushed function: LocArea
        function LocAreaButtonPushed(app, event)
            app.ResultArea.Value = 'G;C;';
            img = imread('GandC.png');
            app.Image_Area.ImageSource = img;
            app.displayStr.Value = '区域定位完成。';
        end

        % Button pushed function: LocBus
        function LocBusButtonPushed(app, event)
            app.ResultBus.Value = '#6335;#3905;';
            imgBus = imread('6335and3905.jpg');
            app.Image_Bus.ImageSource = imgBus;
            app.displayStr.Value = '总线定位完成。';
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case '电压幅值'
                    app.displayStr.Value = '读取电压幅值中……';
                    biaoIndex = readtable('index.xlsx', 'Sheet', 'U');
                    app.UITable.Data = biaoIndex;
            end
        end

        % Value changed function: EditIndex
        function EditIndexValueChanged(app, event)
            value = app.EditIndex.Value;
            dataStructU = importdata('case10BusVolMag.csv');
            dataU = dataStructU.data(38,1:end-1);
            plot(app.UIAxes_time, (0:length(dataU)-1)/30, dataU);
            app.UIAxes_time.XLabel.String = 't/s';
            app.UIAxes_time.YLabel.String = 'Mag/p.u.';
            app.UIAxes_time.XLim = [30,90];
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            switch selectedButton.Text
                case '有功功率'
                    app.displayStr.Value = '读取有功功率中……';
                    biaoIndexFeature = readtable('index.xlsx', 'Sheet', 'I');
                    app.UITableFeature.Data = biaoIndexFeature;
            end
        end

        % Value changed function: EditIndexFeature
        function EditIndexFeatureValueChanged(app, event)
            value = app.EditIndexFeature.Value;
            dataStructP = importdata('case10PowerP.csv');
            dataP = dataStructP.data(60,1:end-1);
            plot(app.UIAxes_Feature, (0:length(dataP)-1)/30, dataP);
            app.UIAxes_Feature.XLabel.String = 't/s';
            app.UIAxes_Feature.YLabel.String = 'Mag/MW';
            app.UIAxes_Feature.Title.String = '有功功率';
            app.UIAxes_Feature.XLim = [30,90];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '强迫振荡源定位软件';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabImageLoc = uitab(app.TabGroup);
            app.TabImageLoc.Title = '源附近单线图';
            app.Image_Bus = uiimage(app.TabImageLoc);
            app.Image_Bus.ImageSource = 'busAll.png';
            app.Image_Area = uiimage(app.TabImageLoc);
            app.Image_Area.ImageSource = 'areaAll.png';
            app.TabVisualInput = uitab(app.TabGroup);
            app.TabVisualInput.Title = '输入分析';
            app.UIAxes_time = uiaxes(app.TabVisualInput);
            title(app.UIAxes_time, '时域曲线图')
            xlabel(app.UIAxes_time, 'X')
            ylabel(app.UIAxes_time, 'Y')
            zlabel(app.UIAxes_time, 'Z')
            app.UITable = uitable(app.TabVisualInput);
            app.UITable.ColumnName = {'index'; 'indexName'; 'columnName'};
            app.UITable.RowName = {};
            app.Label_5 = uilabel(app.TabVisualInput);
            app.Label_5.Text = '参考以下表格，在此输入要观察的索引：';
            app.EditIndex = uieditfield(app.TabVisualInput, 'text');
            app.EditIndex.ValueChangedFcn = createCallbackFcn(app, @EditIndexValueChanged, true);
            app.TabVisualFeature = uitab(app.TabGroup);
            app.TabVisualFeature.Title = '特征可视化';
            app.UIAxes_Feature = uiaxes(app.TabVisualFeature);
            title(app.UIAxes_Feature, 'Title')
            xlabel(app.UIAxes_Feature, 'X')
            ylabel(app.UIAxes_Feature, 'Y')
            zlabel(app.UIAxes_Feature, 'Z')
            app.UITableFeature = uitable(app.TabVisualFeature);
            app.UITableFeature.ColumnName = {'index'; 'indexName'; 'columnName'};
            app.UITableFeature.RowName = {};
            app.Label_6 = uilabel(app.TabVisualFeature);
            app.Label_6.Text = '参考以下表格，在此输入要观察的索引：';
            app.EditIndexFeature = uieditfield(app.TabVisualFeature, 'text');
            app.EditIndexFeature.ValueChangedFcn = createCallbackFcn(app, @EditIndexFeatureValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '系统提示';
            app.displayStr = uieditfield(app.UIFigure, 'text');
            app.Localization = uipanel(app.UIFigure);
            app.Localization.Title = '定位选项';
            app.LocArea = uibutton(app.Localization, 'push');
            app.LocArea.ButtonPushedFcn = createCallbackFcn(app, @LocAreaButtonPushed, true);
            app.LocArea.Text = '区域定位';
            app.Label_3 = uilabel(app.Localization);
            app.Label_3.Text = '振荡源位于区域：';
            app.ResultArea = uieditfield(app.Localization, 'text');
            app.LocBus = uibutton(app.Localization, 'push');
            app.LocBus.ButtonPushedFcn = createCallbackFcn(app, @LocBusButtonPushed, true);
            app.LocBus.Text = '总线定位';
            app.Label_4 = uilabel(app.Localization);
            app.Label_4.Text = '振荡源位于总线：';
            app.ResultBus = uieditfield(app.Localization, 'text');
            app.visual = uipanel(app.UIFigure);
            app.visual.Title = '可视化选项';
            app.ButtonGroup = uibuttongroup(app.visual);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '选择要观察的变量：';
            app.Button_U = uitogglebutton(app.ButtonGroup);
            app.Button_U.Text = '电压幅值';
            app.Button_uj = uitogglebutton(app.ButtonGroup);
            app.Button_uj.Text = '电压相角';
            app.Button_I = uitogglebutton(app.ButtonGroup);
            app.Button_I.Text = '电流幅值';
            app.Button_ij = uitogglebutton(app.ButtonGroup);
            app.Button_ij.Text = '电流相角';
            app.Button_I_2 = uitogglebutton(app.ButtonGroup);
            app.Button_I_2.Text = 'None';
            app.Button_I_2.Value = true;
            app.ButtonGroup_2 = uibuttongroup(app.visual);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Title = '选择要观察的特征：';
            app.NoneButton = uitogglebutton(app.ButtonGroup_2);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.Button_P = uitogglebutton(app.ButtonGroup_2);
            app.Button_P.Text = '有功功率';
            app.Button_Q = uitogglebutton(app.ButtonGroup_2);
            app.Button_Q.Text = '无功功率';
            app.Button_DEF = uitogglebutton(app.ButtonGroup_2);
            app.Button_DEF.Text = '耗散能量流';
            app.Button_stft = uitogglebutton(app.ButtonGroup_2);
            app.Button_stft.Text = '定位图像';
            app.readData = uibutton(app.UIFigure, 'push');
            app.readData.ButtonPushedFcn = createCallbackFcn(app, @readDataButtonPushed, true);
            app.readData.Text = '读入数据';
            app.dataFileName = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end