classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Label_2               matlab.ui.control.Label
        TabGroup2             matlab.ui.container.TabGroup
        Tab_2                 matlab.ui.container.Tab
        stopTimer             matlab.ui.control.Button
        startTimer            matlab.ui.control.Button
        phase                 matlab.ui.control.Knob
        Label_3               matlab.ui.control.Label
        mag                   matlab.ui.control.Knob
        Knob_3Label           matlab.ui.control.Label
        freq                  matlab.ui.control.Knob
        Knob_2Label           matlab.ui.control.Label
        axis1                 matlab.ui.control.UIAxes
        Tab_3                 matlab.ui.container.Tab
        OnOffPanel            matlab.ui.container.Panel
        Switch                matlab.ui.control.Switch
        SwitchLabel           matlab.ui.control.Label
        Lamp                  matlab.ui.control.Lamp
        LampLabel             matlab.ui.control.Label
        Panel_2               matlab.ui.container.Panel
        Button                matlab.ui.control.Button
        EditField             matlab.ui.control.EditField
        Label                 matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        Knob                  matlab.ui.control.Knob
        KnobLabel             matlab.ui.control.Label
        Gauge                 matlab.ui.control.Gauge
        GaugeLabel            matlab.ui.control.Label
        AppDesignerDemoLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function test(app, event)
            value = app.AmplitudeSlider.Value;
            plot(app.axis1, value*peaks)
            app.axis1.YLim = [-1000 1000];
        end

        % Button pushed function: Button
        function locationResults(app, event)
            app.EditField.Value = '区域A；总线1431';
        end

        % Value changing function: Knob
        function KnobValueChanging(app, event)
            changingValue = event.Value;
            app.Gauge.Value = changingValue;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value, 'On') %strcmp-比较字符串
                app.Lamp.Color = [1,0,0];
            else
                app.Lamp.Color = [0,0,1];
            end
        end

        % Value changing function: mag
        function magValueChanging(app, event)
            changingValue = event.Value;
            A = changingValue;
            omega = 2*pi*app.freq.Value;
            ph = app.phase.Value/180*pi;
            t = linspace(0, 10, 1000);
            y = A*sin(omega*t +ph);
            plot(app.axis1,t,y);
        end

        % Value changing function: freq
        function freqValueChanging(app, event)
            changingValue = event.Value;
            A = app.mag.Value;
            omega = 2*pi*changingValue;
            ph = app.phase.Value/180*pi;
            t = linspace(0, 10, 1000);
            y = A*sin(omega*t +ph);
            plot(app.axis1,t,y);
        end

        % Value changing function: phase
        function phaseValueChanging(app, event)
            changingValue = event.Value;            
            A = app.mag.Value;
            omega = 2*pi*app.freq.Value;
            ph = changingValue/180*pi;
            t = linspace(0, 10, 1000);
            y = A*sin(omega*t +ph);
            plot(app.axis1,t,y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AppDesignerDemoLabel = uilabel(app.UIFigure);
            app.AppDesignerDemoLabel.Text = 'AppDesignerDemo';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = '坐标轴';
            app.axis1 = uiaxes(app.Tab_2);
            title(app.axis1, '测试绘图')
            xlabel(app.axis1, 'X')
            ylabel(app.axis1, 'Y')
            zlabel(app.axis1, 'Z')
            app.Knob_2Label = uilabel(app.Tab_2);
            app.Knob_2Label.Text = '频率';
            app.freq = uiknob(app.Tab_2, 'continuous');
            app.freq.Limits = [0 10];
            app.freq.ValueChangingFcn = createCallbackFcn(app, @freqValueChanging, true);
            app.Knob_3Label = uilabel(app.Tab_2);
            app.Knob_3Label.Text = '幅值';
            app.mag = uiknob(app.Tab_2, 'continuous');
            app.mag.ValueChangingFcn = createCallbackFcn(app, @magValueChanging, true);
            app.Label_3 = uilabel(app.Tab_2);
            app.Label_3.Text = '相位';
            app.phase = uiknob(app.Tab_2, 'continuous');
            app.phase.Limits = [-180 180];
            app.phase.ValueChangingFcn = createCallbackFcn(app, @phaseValueChanging, true);
            app.startTimer = uibutton(app.Tab_2, 'push');
            app.startTimer.Text = '启动';
            app.stopTimer = uibutton(app.Tab_2, 'push');
            app.stopTimer.Text = '停止';
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = '基础控件';
            app.Panel = uipanel(app.Tab_3);
            app.Panel.Title = '旋钮 仪表';
            app.GaugeLabel = uilabel(app.Panel);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.Panel, 'circular');
            app.KnobLabel = uilabel(app.Panel);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.Panel, 'continuous');
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.Panel_2 = uipanel(app.Tab_3);
            app.Panel_2.Title = '按钮 编辑字段';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '振荡源位于：';
            app.EditField = uieditfield(app.Panel_2, 'text');
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @locationResults, true);
            app.Button.Text = '定位结果';
            app.OnOffPanel = uipanel(app.Tab_3);
            app.OnOffPanel.Title = '开关控灯（On红，Off蓝）';
            app.LampLabel = uilabel(app.OnOffPanel);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.OnOffPanel);
            app.SwitchLabel = uilabel(app.OnOffPanel);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.OnOffPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '入门练习';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end